classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        bEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.EditField
        VARIABLEEditField_2Label      matlab.ui.control.Label
        VARIABLEEditField_2           matlab.ui.control.EditField
        PDFEditFieldLabel             matlab.ui.control.Label
        PDFEditField                  matlab.ui.control.EditField
        aEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.EditField
        NORMALIZATIONFACTOREditFieldLabel  matlab.ui.control.Label
        NORMALIZATIONFACTOREditField  matlab.ui.control.EditField
        DIFFERENTIALENTROPYEditFieldLabel  matlab.ui.control.Label
        DIFFERENTIALENTROPYEditField  matlab.ui.control.EditField
        GAUSSIANUPPERBOUNDEditFieldLabel  matlab.ui.control.Label
        GAUSSIANUPPERBOUNDEditField   matlab.ui.control.EditField
        EXERCISE1Label                matlab.ui.control.Label
        GOButton                      matlab.ui.control.StateButton
        INSERTFIELDSLabel             matlab.ui.control.Label
        RESULTSLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GOButton
        function GOButtonValueChanged(app, event)
            % Inserimenti
            variabile = app.VARIABLEEditField_2.Value;
            pdf_inserita = vectorize(app.PDFEditField.Value);
            pdf_inserita = strcat('@(', variabile, ')', pdf_inserita);
            pdf_inserita = str2func(pdf_inserita);
            a = str2double(app.aEditField.Value);
            b = str2double(app.bEditField.Value);
            
            % Sanity
            if isnan(a) || isempty(a) || isempty(variabile) || isnan(b) || isempty(b)
                uialert(app.UIFigure, "Missing variable","Error");
                return
            end
            
            % Computazione
            fattore_normalizzazione = integral(pdf_inserita, a, b);
            pdf_normalizzata = @(x) pdf_inserita(x) / fattore_normalizzazione;
            funzione_integranda = @(x) pdf_normalizzata(x).*log2(pdf_normalizzata(x));
            entropia_differenziale = -integral(funzione_integranda,a,b);
            valore_atteso = integral(@(x) x.*pdf_normalizzata(x), a, b);
            momento_2 = integral(@(x) x.^2.*pdf_normalizzata(x), a, b);
            varianza = momento_2 - valore_atteso.^2;
            limite_superiore_gaussiano = (1/2) * log2(2 * pi * exp(1) * varianza);
            
            % Assegnazione
            app.NORMALIZATIONFACTOREditField.Value = num2str(fattore_normalizzazione);
            app.DIFFERENTIALENTROPYEditField.Value = num2str(entropia_differenziale);
            app.GAUSSIANUPPERBOUNDEditField.Value = num2str(limite_superiore_gaussiano);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'text');
            app.VARIABLEEditField_2Label = uilabel(app.UIFigure);
            app.VARIABLEEditField_2Label.Text = 'VARIABLE';
            app.VARIABLEEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PDFEditFieldLabel = uilabel(app.UIFigure);
            app.PDFEditFieldLabel.Text = 'PDF';
            app.PDFEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.NORMALIZATIONFACTOREditFieldLabel = uilabel(app.UIFigure);
            app.NORMALIZATIONFACTOREditFieldLabel.Text = 'NORMALIZATION FACTOR';
            app.NORMALIZATIONFACTOREditField = uieditfield(app.UIFigure, 'text');
            app.DIFFERENTIALENTROPYEditFieldLabel = uilabel(app.UIFigure);
            app.DIFFERENTIALENTROPYEditFieldLabel.Text = 'DIFFERENTIAL ENTROPY';
            app.DIFFERENTIALENTROPYEditField = uieditfield(app.UIFigure, 'text');
            app.GAUSSIANUPPERBOUNDEditFieldLabel = uilabel(app.UIFigure);
            app.GAUSSIANUPPERBOUNDEditFieldLabel.Text = 'GAUSSIAN UPPER BOUND';
            app.GAUSSIANUPPERBOUNDEditField = uieditfield(app.UIFigure, 'text');
            app.EXERCISE1Label = uilabel(app.UIFigure);
            app.EXERCISE1Label.Text = 'EXERCISE 1';
            app.GOButton = uibutton(app.UIFigure, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @GOButtonValueChanged, true);
            app.GOButton.Text = 'GO';
            app.INSERTFIELDSLabel = uilabel(app.UIFigure);
            app.INSERTFIELDSLabel.Text = 'INSERT FIELDS:';
            app.RESULTSLabel = uilabel(app.UIFigure);
            app.RESULTSLabel.Text = 'RESULTS:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end