classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EXERCISE2Label                matlab.ui.control.Label
        INSERTLabel                   matlab.ui.control.Label
        RANGEXEditFieldLabel          matlab.ui.control.Label
        RANGEXEditField               matlab.ui.control.EditField
        RANGEYEditFieldLabel          matlab.ui.control.Label
        RANGEYEditField               matlab.ui.control.EditField
        RANGEZEditFieldLabel          matlab.ui.control.Label
        RANGEZEditField               matlab.ui.control.EditField
        XPDFEditFieldLabel            matlab.ui.control.Label
        XPDFEditField                 matlab.ui.control.EditField
        YPDFEditFieldLabel            matlab.ui.control.Label
        YPDFEditField                 matlab.ui.control.EditField
        ZPDFEditFieldLabel            matlab.ui.control.Label
        ZPDFEditField                 matlab.ui.control.EditField
        FUNCTIONEditFieldLabel        matlab.ui.control.Label
        FUNCTIONEditField             matlab.ui.control.EditField
        RESULTSLabel                  matlab.ui.control.Label
        HXEditFieldLabel              matlab.ui.control.Label
        HXEditField                   matlab.ui.control.EditField
        HYEditFieldLabel              matlab.ui.control.Label
        HYEditField                   matlab.ui.control.EditField
        HZEditFieldLabel              matlab.ui.control.Label
        HZEditField                   matlab.ui.control.EditField
        HFUNCTIONEditFieldLabel       matlab.ui.control.Label
        HFUNCTIONEditField            matlab.ui.control.EditField
        INEQUALITIESHXEditFieldLabel  matlab.ui.control.Label
        INEQUALITIESHXEditField       matlab.ui.control.EditField
        INEQUALITIESHYEditFieldLabel  matlab.ui.control.Label
        INEQUALITIESHYEditField       matlab.ui.control.EditField
        INEQUALITIESHZEditFieldLabel  matlab.ui.control.Label
        INEQUALITIESHZEditField       matlab.ui.control.EditField
        INEQUALITIESHFUNCEditFieldLabel  matlab.ui.control.Label
        INEQUALITIESHFUNCEditField    matlab.ui.control.EditField
        GOButton                      matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GOButton
        function GOButtonValueChanged(app, event)
            %value = app.GOButton.Value;
            % Assegnazione
            range_x = app.RANGEXEditField.Value;
            range_y = app.RANGEYEditField.Value;
            range_z = app.RANGEZEditField.Value;
            pdf_x = app.XPDFEditField.Value;
            pdf_y = app.YPDFEditField.Value;
            pdf_z = app.ZPDFEditField.Value;
            funzione_inserita = app.FUNCTIONEditField.Value;

            % Sanity
            if isempty(range_x) || isempty(range_y) || isempty(range_z) || isempty(pdf_x) || isempty(pdf_y) || isempty(pdf_z) || isempty(funzione_inserita)
                uialert(app.UIFigure, "Missing values","Error");
                return
            end

            range_x = str2num(range_x);
            range_y = str2num(range_y);
            range_z = str2num(range_z);
            pdf_x = str2num(pdf_x);
            pdf_x = pdf_x/sum(pdf_x);
            pdf_y = str2num(pdf_y);
            pdf_y = pdf_y/sum(pdf_y);
            pdf_z = str2num(pdf_z);
            pdf_z = pdf_z/sum(pdf_z);

            funzione_inserita = strcat('@(x,y,z)', funzione_inserita);
            funzione_inserita = str2func(funzione_inserita);

            % Computazioni
            H_x = -sum(pdf_x.*log2(pdf_x));
            H_y = -sum(pdf_y.*log2(pdf_y));
            H_z = -sum(pdf_z.*log2(pdf_z));

            % Creazione probability functions 
            p_x = containers.Map(range_x, pdf_x);
            p_y = containers.Map(range_y, pdf_y);
            p_z = containers.Map(range_z, pdf_z);
            p_funzione = containers.Map('KeyType','double','ValueType','double');

            % Calcolo probability function della funzione
            for x = range_x
                for y = range_y
                    for z = range_z
                        key = funzione_inserita(x,y,z);
                        if ~isKey(p_funzione, key)
                            p_funzione(key) = p_x(x)*p_y(y)*p_z(z);
                        else
                            p_funzione(key) = p_funzione(key) + p_x(x)*p_y(y)*p_z(z);
                        end
                    end
                end
            end
            
            H_function = -sum(cell2mat(values(p_funzione)).*log2(cell2mat(values(p_funzione))));

            % Limite raggiunto quando le probabilità sono uniformi (0<=H(X)<=log2(N))
            limite_superiore_Hx = log2(length(pdf_x));
            limite_superiore_Hy = log2(length(pdf_y));
            limite_superiore_Hz = log2(length(pdf_z));
            P = zeros(length(pdf_x), length(pdf_y), length(pdf_z));
            for x = 1:length(pdf_x)
                for y = 1:length(pdf_y)
                    for z = 1:length(pdf_z)
                        P(x,y,z) = pdf_x(x)*pdf_y(y)*pdf_z(z);
                    end
                end
            end
            limite_superiore_Hxyz = -sum(P(:).*log2(P(:)));

            % Assegnazioni
            app.HXEditField.Value = num2str(H_x);
            app.HYEditField.Value = num2str(H_y);
            app.HZEditField.Value = num2str(H_z);
            app.HFUNCTIONEditField.Value = num2str(H_function);
            if  H_x <= limite_superiore_Hx && H_x >= 0
                app.INEQUALITIESHXEditField.Value = 'Satisfied';
            else
                app.INEQUALITIESHXEditField.Value = 'Not satisfied';
            end
                
            if  H_y <= limite_superiore_Hy && H_y >= 0
                app.INEQUALITIESHYEditField.Value = 'Satisfied';
            else
                app.INEQUALITIESHYEditField.Value = 'Not satisfied';
            end

            if  H_z <= limite_superiore_Hz && H_z >= 0
                app.INEQUALITIESHZEditField.Value = 'Satisfied';
            else
                app.INEQUALITIESHZEditField.Value = 'Not satisfied';
            end
            
            if  H_function <= limite_superiore_Hxyz && H_function >= 0
                app.INEQUALITIESHFUNCEditField.Value = 'Satisfied';
            else
                app.INEQUALITIESHFUNCEditField.Value = 'Not satisfied';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EXERCISE2Label = uilabel(app.UIFigure);
            app.EXERCISE2Label.Text = 'EXERCISE 2';
            app.INSERTLabel = uilabel(app.UIFigure);
            app.INSERTLabel.Text = 'INSERT:';
            app.RANGEXEditFieldLabel = uilabel(app.UIFigure);
            app.RANGEXEditFieldLabel.Text = 'RANGE X';
            app.RANGEXEditField = uieditfield(app.UIFigure, 'text');
            app.RANGEYEditFieldLabel = uilabel(app.UIFigure);
            app.RANGEYEditFieldLabel.Text = 'RANGE Y';
            app.RANGEYEditField = uieditfield(app.UIFigure, 'text');
            app.RANGEZEditFieldLabel = uilabel(app.UIFigure);
            app.RANGEZEditFieldLabel.Text = 'RANGE Z';
            app.RANGEZEditField = uieditfield(app.UIFigure, 'text');
            app.XPDFEditFieldLabel = uilabel(app.UIFigure);
            app.XPDFEditFieldLabel.Text = 'X PDF';
            app.XPDFEditField = uieditfield(app.UIFigure, 'text');
            app.YPDFEditFieldLabel = uilabel(app.UIFigure);
            app.YPDFEditFieldLabel.Text = 'Y PDF';
            app.YPDFEditField = uieditfield(app.UIFigure, 'text');
            app.ZPDFEditFieldLabel = uilabel(app.UIFigure);
            app.ZPDFEditFieldLabel.Text = 'Z PDF';
            app.ZPDFEditField = uieditfield(app.UIFigure, 'text');
            app.FUNCTIONEditFieldLabel = uilabel(app.UIFigure);
            app.FUNCTIONEditFieldLabel.Text = 'FUNCTION';
            app.FUNCTIONEditField = uieditfield(app.UIFigure, 'text');
            app.RESULTSLabel = uilabel(app.UIFigure);
            app.RESULTSLabel.Text = 'RESULTS:';
            app.HXEditFieldLabel = uilabel(app.UIFigure);
            app.HXEditFieldLabel.Text = 'H(X)';
            app.HXEditField = uieditfield(app.UIFigure, 'text');
            app.HYEditFieldLabel = uilabel(app.UIFigure);
            app.HYEditFieldLabel.Text = 'H(Y)';
            app.HYEditField = uieditfield(app.UIFigure, 'text');
            app.HZEditFieldLabel = uilabel(app.UIFigure);
            app.HZEditFieldLabel.Text = 'H(Z)';
            app.HZEditField = uieditfield(app.UIFigure, 'text');
            app.HFUNCTIONEditFieldLabel = uilabel(app.UIFigure);
            app.HFUNCTIONEditFieldLabel.Text = 'H(FUNCTION)';
            app.HFUNCTIONEditField = uieditfield(app.UIFigure, 'text');
            app.INEQUALITIESHXEditFieldLabel = uilabel(app.UIFigure);
            app.INEQUALITIESHXEditFieldLabel.Text = 'INEQUALITIES H(X)';
            app.INEQUALITIESHXEditField = uieditfield(app.UIFigure, 'text');
            app.INEQUALITIESHYEditFieldLabel = uilabel(app.UIFigure);
            app.INEQUALITIESHYEditFieldLabel.Text = 'INEQUALITIES H(Y)';
            app.INEQUALITIESHYEditField = uieditfield(app.UIFigure, 'text');
            app.INEQUALITIESHZEditFieldLabel = uilabel(app.UIFigure);
            app.INEQUALITIESHZEditFieldLabel.Text = 'INEQUALITIES H(Z)';
            app.INEQUALITIESHZEditField = uieditfield(app.UIFigure, 'text');
            app.INEQUALITIESHFUNCEditFieldLabel = uilabel(app.UIFigure);
            app.INEQUALITIESHFUNCEditFieldLabel.Text = 'INEQUALITIES H(FUNC)';
            app.INEQUALITIESHFUNCEditField = uieditfield(app.UIFigure, 'text');
            app.GOButton = uibutton(app.UIFigure, 'state');
            app.GOButton.ValueChangedFcn = createCallbackFcn(app, @GOButtonValueChanged, true);
            app.GOButton.Text = 'GO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end